classdef appSnake < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        ButtonGroup_2  matlab.ui.container.ButtonGroup
        Button_7       matlab.ui.control.ToggleButton
        Button_6       matlab.ui.control.ToggleButton
        Button_5       matlab.ui.control.ToggleButton
        Button_4       matlab.ui.control.Button
        TextArea       matlab.ui.control.TextArea
        Label          matlab.ui.control.Label
        ButtonGroup    matlab.ui.container.ButtonGroup
        Button_2       matlab.ui.control.ToggleButton
        Button_3       matlab.ui.control.ToggleButton
        Button         matlab.ui.control.ToggleButton
        CenterPanel    matlab.ui.container.Panel
        UIAxes         matlab.ui.control.UIAxes
        RightPanel     matlab.ui.container.Panel
        TextArea_3     matlab.ui.control.TextArea
        Label_4        matlab.ui.control.Label
        UIAxes2        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        propertyPrivate % Description
        
    end
    
    properties (Access = public)
        propertyPublic % Description
        % 游戏参数
        playerNumber=1% 游玩人数，支持1，2，3，4人游玩
        difficulty=1% 难度分为easy,normal,hard，对应难度系数1，2，3
        gameWidth=10;% 游戏区域宽度
        gameHeight=10;% 游戏区域高度
        state='start';% 游戏状态，未启用，目前有开始界面，运行界面，暂停界面，结束界面
        % 蛇1参数
        snake1=struct( ...
            'alive',true, ...% 活着
            'length',3, ...% 蛇的初始长度为3，蛇长也是得分
            'direction','east', ...% 蛇的方向
            'position',[ ...
                 3,2; ...
                 2,2; ...
                 1,2; ...
                 ], ...% 蛇的位置，从头到尾记录
             'speed',1 ...% 蛇移动速度
             );
        % 蛇2参数
        snake2=struct( ...
            'alive',true, ...
            'length',3, ...% 蛇的初始长度为3，蛇长也是得分
            'direction','east', ...% 蛇的方向
            'position',[ ...
                 3,3; ...
                 2,3; ...
                 1,3; ...
                 ], ...% 蛇的位置，从头到尾记录
             'speed',1 ...% 蛇移动速度
             );
        % 蛇3参数
        snake3=struct( ...
            'alive',true, ...
            'length',3, ...% 蛇的初始长度为3，蛇长也是得分
            'direction','east', ...% 蛇的方向
            'position',[ ...
                 3,4; ...
                 2,4; ...
                 1,4; ...
                 ], ...% 蛇的位置，从头到尾记录
             'speed',1 ...% 蛇移动速度
             );
        maxSnakeLength=3
        food=[3,3]% 食物，以坐标形式存储
        temp=0
        timeKeeper=timer()

    end
    
    methods (Access = private)
        
        function CatchKeyboard(app,~,event)
            switch event.Key
                case 'w'
                    app.snake1.direction='north';
                case 's'
                    app.snake1.direction='south';
                case 'a'
                    app.snake1.direction='west';
                case 'd'
                    app.snake1.direction='east';
                case 'uparrow'
                    app.snake2.direction='north';
                case 'downarrow'
                    app.snake2.direction='south';
                case 'leftarrow'
                    app.snake2.direction='west';
                case 'rightarrow'
                    app.snake2.direction='east';
            end
        end
        
        function results=GenerateFood(app)% 随机生成一个食物的位置
            results=[randi(app.gameWidth),randi(app.gameHeight)];
        end
        
        function CalculateNextPosition(app)% 计算蛇的位置，包含碰撞检测，吃食物变长
            if app.playerNumber>=1
                switch app.snake1.direction% 方向计算
                    case 'east'
                        app.snake1.newHead=app.snake1.position(1,:)+[1,0];
                    case 'south'
                        app.snake1.newHead=app.snake1.position(1,:)+[0,-1];
                    case 'west'
                        app.snake1.newHead=app.snake1.position(1,:)+[-1,0];
                    case 'north'
                        app.snake1.newHead=app.snake1.position(1,:)+[0,1];
                end
                if app.snake1.newHead==app.food% 碰撞检测
                    app.snake1.position=[app.snake1.newHead;app.snake1.position];
                    app.snake1.length=app.snake1.length+1;
                    app.food=GenerateFood(app);
                elseif any(app.snake1.newHead<=0)||app.snake1.newHead(1)>=app.gameWidth||app.snake1.newHead(2)>=app.gameHeight

                    app.snake1.alive=false;
                else
                    app.snake1.position=[app.snake1.newHead;app.snake1.position(1:(end-1),:)];
                end
            end
            if app.playerNumber>=2
                switch app.snake2.direction
                    case 'east'
                        app.snake2.newHead=app.snake2.position(1,:)+[1,0];
                    case 'south'
                        app.snake2.newHead=app.snake2.position(1,:)+[0,-1];
                    case 'west'
                        app.snake2.newHead=app.snake2.position(1,:)+[-1,0];
                    case 'north'
                        app.snake2.newHead=app.snake2.position(1,:)+[0,1];
                end
                if app.snake2.newHead==app.food% 碰撞检测
                    app.snake2.position=[app.snake2.newHead;app.snake2.position];
                    app.snake2.length=app.snake2.length+1;
                    app.food=GenerateFood(app);
                elseif any(app.snake2.newHead<=0)||app.snake2.newHead(1)>=app.gameWidth||app.snake2.newHead(2)>=app.gameHeight

                    app.snake2.alive=false;
                else
                    app.snake2.position=[app.snake2.newHead;app.snake2.position(1:(end-1),:)];
                end
            end
            if app.playerNumber>=3
                switch app.snake3.direction
                    case 'east'
                        app.snake3.newHead=app.snake3.position(1,:)+[1,0];
                    case 'south'
                        app.snake3.newHead=app.snake3.position(1,:)+[0,-1];
                    case 'west'
                        app.snake3.newHead=app.snake3.position(1,:)+[-1,0];
                    case 'north'
                        app.snake3.newHead=app.snake3.position(1,:)+[0,1];
                end
                if app.snake3.newHead==app.food% 碰撞检测
                    app.snake3.position=[app.snake3.newHead;app.snake3.position];
                    app.snake3.length=app.snake3.length+1;
                    app.food=GenerateFood(app);
                elseif any(app.snake3.newHead<=0)||app.snake3.newHead(1)>=app.gameWidth||app.snake3.newHead(2)>=app.gameHeight

                    app.snake3.alive=false;
                else
                    app.snake3.position=[app.snake3.newHead;app.snake3.position(1:(end-1),:)];
                end
            end
            

        end

        function Draw(app)% 绘制游戏界面
            % 更新游戏区域
            switch app.playerNumber
                case 1
                    app.maxSnakeLength=app.snake1.length;
                case 2
                    app.maxSnakeLength=max([app.snake1.length,app.snake2.length]);
                case 3
                    app.maxSnakeLength=max([app.snake1.length,app.snake2.length,app.snake3.length]);
            end
            app.gameWidth=5+floor(sqrt(app.playerNumber)*app.maxSnakeLength/app.difficulty);% 游戏区域宽度，游玩人数的平方根乘以蛇的最长长度除以难度系数
            app.gameHeight=5+floor(sqrt(app.playerNumber)*app.maxSnakeLength/app.difficulty);% 游戏区域高度，游玩人数的平方根乘以蛇的最长长度除以难度系数
            
            % 划定游戏区域
            xlim(app.UIAxes,[1 app.gameWidth])
            ylim(app.UIAxes,[1 app.gameHeight])

            cla(app.UIAxes)% 清除上一帧
            cla(app.UIAxes2)

            hold(app.UIAxes,'on');
            for i=1:app.gameHeight% 绘制横线
                plot(app.UIAxes,[0,app.gameWidth],[i,i],'Color','black');
            end
            for i=1:app.gameWidth% 绘制竖线
                plot(app.UIAxes,[i,i],[0,app.gameHeight],'Color','black');
            end
            rectangle( ...
                app.UIAxes, ...
                'Position',[app.food(1,1),app.food(1,2),1,1], ...
                'FaceColor',[0.9,0.1,0.1], ...
                'EdgeColor','none', ...
                'Curvature',[1,1] ...
                );% 绘制食物
            if app.playerNumber>=1% 绘制蛇1
                for i=2:size(app.snake1.position,1)
                    rectangle( ...
                        app.UIAxes, ...
                        'Position',[floor(app.snake1.position(i,1)),floor(app.snake1.position(i,2)),1,1], ...
                        'FaceColor',[0.5,1,0.5] ... % 绿色填充
                        );
                end
                rectangle(app.UIAxes,'Position',[floor(app.snake1.position(1,1)),floor(app.snake1.position(1,2)),1,1],'FaceColor',[0,1,0])
                bar(app.UIAxes2,app.snake1.length,'FaceColor',[0,1,0])
            end
            if app.playerNumber>=2% 绘制蛇2
                for i=2:size(app.snake2.position,1)
                    rectangle( ...
                        app.UIAxes, ...
                        'Position',[floor(app.snake2.position(i,1)),floor(app.snake2.position(i,2)),1,1], ...
                        'FaceColor',[1,0.5,0.5] ... % 红色填充
                        );
                end
                rectangle(app.UIAxes,'Position',[floor(app.snake2.position(1,1)),floor(app.snake2.position(1,2)),1,1],'FaceColor',[1,0,0])
                bar(app.UIAxes2,[app.snake1.length,app.snake2.length],'FaceColor','flat','CData',[0,1,0;1,0,0])
            end
            if app.playerNumber>=3% 绘制蛇3
                for i=2:size(app.snake3.position,1)
                    rectangle( ...
                        app.UIAxes, ...
                        'Position',[floor(app.snake3.position(i,1)),floor(app.snake3.position(i,2)),1,1], ...
                        'FaceColor',[0.5,0.5,1] ... % 蓝色填充
                        );
                end
                rectangle(app.UIAxes,'Position',[floor(app.snake3.position(1,1)),floor(app.snake3.position(1,2)),1,1],'FaceColor',[0,0,1])
                bar(app.UIAxes2,[app.snake1.length,app.snake2.length],'FaceColor','flat','CData',[0,1,0;1,0,0;0,0,1])
            end
            hold(app.UIAxes,'off');


        end

        function MainLoop(app,~,~)% 游戏主函数
            
            CalculateNextPosition(app)
            Draw(app)

            try% 检查运行次数
                app.temp=app.temp+1;
                app.TextArea_3.Value=num2str(app.temp);
                disp(['执行次数: ',num2str(app.temp)]);
            catch ME
                disp('回调错误:');
                disp(ME.message);
            end

        end

        
        function FinishGame(app,~,~)
            stop(app.timeKeeper)
            delete(app.timeKeeper)
            delete(app.UIFigure)
        end
    end
    
    methods (Access = public)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app.UIFigure.KeyPressFcn = @app.CatchKeyboard;
        end

        % Selection changed function: ButtonGroup
        function ChangePlayerNumber(app, event)
            switch event.NewValue.Text
                case '独闯荒野'
                    app.playerNumber=1;
                case '结伴同行'
                    app.playerNumber=2;
                case '三人成行'
                    app.playerNumber=3;
                otherwise
                    app.playerNumber=1;
            end
        end

        % Selection changed function: ButtonGroup_2
        function ChangeDifficulty(app, event)
            switch event.NewValue.Text
                case '简单'
                    app.difficulty=1;
                    app.TextArea.Value='小蛇蛇爬得较慢，游戏区域较大，并且小蛇蛇会主动跨过自己的身子';
                case '普通'
                    app.difficulty=2;
                    app.TextArea.Value='小蛇蛇爬得速度适中，游戏区域适中，小蛇蛇会主动跨过自己的身子，但是不太灵活，不再能往后爬';
                case '困难'
                    app.difficulty=3;
                    app.TextArea.Value='小蛇蛇爬得飞快，游戏区域较小，小蛇蛇饿疯了，会吃掉所有遇到的东西，包括自己的身子';
                otherwise
                    app.difficulty=1;
                    app.TextArea.Value='未知难度';
            end
        end

        % Button pushed function: Button_4
        function StartGame(app, event)
            % 开始按钮变为结束按钮
            app.Button_4.Text='结束游戏';
            app.Button_4.ButtonPushedFcn=@app.FinishGame;

            app.ButtonGroup.Enable='off';
            app.ButtonGroup_2.Enable='off';
            focus(app.UIFigure)% 需要调整焦点至app.UIAxes，否则需要手动点一下
            % 蛇参数
            app.snake1.speed=0.1*log(app.snake1.length)*app.difficulty;% 蛇移动速度与蛇长为对数关系，乘以难度系数
            
            app.timeKeeper=timer( ...
                'TasksToExecute',Inf, ...
                'TimerFcn',@app.MainLoop, ...
                'ExecutionMode','fixedRate' ...
                );% 计时器，用于循环游戏主函数

            start(app.timeKeeper)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChangePlayerNumber, true);
            app.ButtonGroup.Title = '游戏模式';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = '独闯荒野';
            app.Button.Value = true;
            app.Button_3 = uitogglebutton(app.ButtonGroup);
            app.Button_3.Text = '结伴同行';
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '三人成行';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '说明';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Placeholder = '点击对应的游戏难度查看说明';
            app.Button_4 = uibutton(app.LeftPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @StartGame, true);
            app.Button_4.Text = '开始游戏';
            app.ButtonGroup_2 = uibuttongroup(app.LeftPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ChangeDifficulty, true);
            app.ButtonGroup_2.Title = '游戏难度';
            app.Button_5 = uitogglebutton(app.ButtonGroup_2);
            app.Button_5.Text = '简单';
            app.Button_5.Value = true;
            app.Button_6 = uitogglebutton(app.ButtonGroup_2);
            app.Button_6.Text = '普通';
            app.Button_7 = uitogglebutton(app.ButtonGroup_2);
            app.Button_7.Text = '困难';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, '游戏区域')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, '蛇长')
            xlabel(app.UIAxes2, '蛇')
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '控制台';
            app.TextArea_3 = uitextarea(app.RightPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appSnake

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end